classdef demoGUItabs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        DomainTab                    matlab.ui.container.Tab
        UIAxes3                      matlab.ui.control.UIAxes
        DomainConfigurationPanel     matlab.ui.container.Panel
        domainDropDownLabel          matlab.ui.control.Label
        domainDropDown               matlab.ui.control.DropDown
        loadeddomainLabel            matlab.ui.control.Label
        DOFDropDownLabel             matlab.ui.control.Label
        DOFDropDown                  matlab.ui.control.DropDown
        DescriptionTextAreaLabel     matlab.ui.control.Label
        DescriptionTextArea          matlab.ui.control.TextArea
        TrainingTab                  matlab.ui.container.Tab
        ModelConfigurationPanel      matlab.ui.container.Panel
        IlluminationTab              matlab.ui.container.Tab
        QDConfigurationPanel         matlab.ui.container.Panel
        ArchiveDropDownLabel         matlab.ui.control.Label
        ArchiveDropDown              matlab.ui.control.DropDown
        nogenerationsEditFieldLabel  matlab.ui.control.Label
        nogenerationsEditField       matlab.ui.control.NumericEditField
        featurexaxisDropDownLabel    matlab.ui.control.Label
        featurexaxisDropDown         matlab.ui.control.DropDown
        featureyaxisDropDownLabel    matlab.ui.control.Label
        featureyaxisDropDown         matlab.ui.control.DropDown
        UserSelectionPressureSliderLabel  matlab.ui.control.Label
        UserSelectionPressureSlider  matlab.ui.control.Slider
        QDcommandsPanel              matlab.ui.container.Panel
        InitRestartButton            matlab.ui.control.Button
        RedrawButton                 matlab.ui.control.Button
        ExplicitButton               matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes3_2                    matlab.ui.control.UIAxes
        IterationListBoxLabel        matlab.ui.control.Label
        IterationListBox             matlab.ui.control.ListBox
        UIAxes6                      matlab.ui.control.UIAxes
        UIAxes7                      matlab.ui.control.UIAxes
        UIAxes7_2                    matlab.ui.control.UIAxes
        UIAxes8                      matlab.ui.control.UIAxes
        AnalysisSelectionTab         matlab.ui.container.Tab
        AnalysisPanel                matlab.ui.container.Panel
        GeneticPrototypingButton     matlab.ui.control.Button
        noprototypesSliderLabel      matlab.ui.control.Label
        noprototypesSlider           matlab.ui.control.Slider
        UIAxes2                      matlab.ui.control.UIAxes
        UserSelectionPanel           matlab.ui.container.Panel
        AddSelectionButton           matlab.ui.control.Button
        RemoveButton                 matlab.ui.control.Button
        consoleTextAreaLabel         matlab.ui.control.Label
        consoleTextArea              matlab.ui.control.TextArea
        IterationListBox_2Label      matlab.ui.control.Label
        IterationListBox_2           matlab.ui.control.ListBox
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes5_2                    matlab.ui.control.UIAxes
        UIAxes6_2                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        p % Algorithm configuration
        d % Domain configuration
        map % Current map object
        classification % Classification
        selectedPrototypes % Keep selection saved here
        constraints % User constraints
        iteration % PRODUQD iteration
        fitfun % Function pointer to current fitness function
    end
    
    methods (Access = private)
        
        function results = setDomain(varargin)
            % Load domain
            app = varargin{1};
            DOMAIN = varargin{2};
            rmpath(genpath('domain')); addpath(['domain/' DOMAIN]);
            dof = 'default'; if nargin > 2; dof = varargin{3}; end
            app.d{app.iteration} = domain(dof);
            app.loadeddomainLabel.Text = ['DOMAIN: ' DOMAIN];
            % Set features to GUI
            app.featurexaxisDropDown.Items = app.d{app.iteration}.featureLabels;
            app.featureyaxisDropDown.Items = app.d{app.iteration}.featureLabels;
            app.featurexaxisDropDown.Value = app.featurexaxisDropDown.Items{1};
            app.featureyaxisDropDown.Value = app.featureyaxisDropDown.Items{2};
            
            app.d{app.iteration}.penaltyWeight = app.UserSelectionPressureSlider.Value;
            % Load example
            app.UIAxes3.cla;
            showExample(app.UIAxes3,app.d{app.iteration});
            % Load description
            app.DescriptionTextArea.Value = app.d{app.iteration}.description;
        end
        
        function showHistory(app,handle)
            cla(handle);
            numCols = length(app.constraints);
            for i=1:numCols
                numPrototypes = length(app.constraints{i}.selectedClasses);
                placement = [];
                for j=1:numPrototypes
                    placement(j,:) = [(i-1) (j-1)]*app.d{app.iteration}.spacer;
                end
                showExample(handle,app.d{app.iteration},app.classification{i}.protoX(app.constraints{i}.selectedClasses,:),placement);
            end
            axis(handle,'equal');
        end
        
        function results = setQD(app,ALGORITHM)
            rmpath('grid'); rmpath('voronoi'); addpath(ALGORITHM);
            % Load configuration
            if ~isempty(app.p) && isfield(app.p{app.iteration},'nGens')
                nGens = app.p{app.iteration}.nGens;
                app.p{app.iteration} = defaultParamSet(4);
                app.p{app.iteration}.nGens = nGens;
            else
                app.p{app.iteration} = defaultParamSet(4);
            end
            app.nogenerationsEditField.Value = app.p{app.iteration}.nGens;
        end
        
        function results = copyFigure(app,axFrom,axTo)
            if ~isempty(axFrom.Children)
                cla(axTo);
                for i=length(axFrom.Children):-1:1
                    axFrom.Children(i).Parent = axTo;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('.'));
            app.iteration = 1;
            app.selectedPrototypes = {};
            app.constraints = {};
            app.d = {};
            app.p = {};
            DOMAIN = app.domainDropDown.Value; %npoly_ffd npoly dropwave shubert4
            DOF = str2num(app.DOFDropDown.Value);
            setDomain(app, DOMAIN, DOF);
            ALGORITHM = app.ArchiveDropDown.Value;
            setQD(app,ALGORITHM);
        end

        % Button pushed function: InitRestartButton
        function InitRestartButtonPushed(app, event)
            constraints = app.constraints; if length(app.constraints) >= app.iteration; constraints = app.constraints(1:app.iteration); end
            app.fitfun = @(x) objective(x,app.d{app.iteration}.fitfun,constraints,app.p{app.iteration}.penaltyWeight,app.p{app.iteration}.driftThreshold,app.UIAxes8);
            if isempty(app.constraints)
                sobSequence         = scramble(sobolset(app.d{app.iteration}.dof,'Skip',1e3),'MatousekAffineOwen');
                sobPoint            = 1;
                initSamples         = range(app.d{app.iteration}.ranges).*sobSequence(sobPoint:(sobPoint+app.p{app.iteration}.numInitSamples)-1,:)+app.d{app.iteration}.ranges(1);
            else
                % Get seeds
                initSamples = [];
                for it1=1:length(app.constraints)
                    initSamples = [initSamples; app.constraints{it1}.members];
                end
            end
            %TODO change to objective()
            [fitness, values]   = app.fitfun(initSamples);
            
            obsMap              = createMap(app.d{app.iteration}, app.p{app.iteration});
            [replaced, replacement, features] = nicheCompete(initSamples, fitness, values, obsMap, app.d{app.iteration}, app.p{app.iteration});
            obsMap              = updateMap(replaced,replacement,obsMap,fitness,initSamples,features);
            [app.map{app.iteration}, ~, ~, ~, ~] = illuminate(app.fitfun,obsMap,app.p{app.iteration},app.d{app.iteration},app.UIAxes);

            % Show data
            app.copyFigure(app.UIAxes7,app.UIAxes7_2);
            app.ExplicitButtonPushed([]);
        end

        % Value changed function: nogenerationsEditField
        function nogenerationsEditFieldValueChanged(app, event)
            value = app.nogenerationsEditField.Value;
            app.p{app.iteration}.nGens = value;
            app.nogenerationsEditField.Value = value;
        end

        % Value changed function: domainDropDown
        function domainDropDownValueChanged(app, event)
            dof = app.domainDropDown.Value;
            setDomain(app, app.domainDropDown.Value, dof);
        end

        % Value changed function: featurexaxisDropDown
        function featurexaxisDropDownValueChanged(app, event)
            value = app.featurexaxisDropDown.Value;
            col = find(strcmp(value,app.d{app.iteration}.featureLabels));
            app.d{app.iteration}.selectedFeatures(1) = col;
        end

        % Value changed function: featureyaxisDropDown
        function featureyaxisDropDownValueChanged(app, event)
            value = app.featureyaxisDropDown.Value;
            col = find(strcmp(value,app.d{app.iteration}.featureLabels));
            app.d{app.iteration}.selectedFeatures(2) = col;
        end

        % Button pushed function: RedrawButton
        function RedrawButtonPushed(app, event)
            app.UIAxes.cla;
            disp('TODO: UPDATE MAP!! BUT KEEP ORIGINAL')
            viewMap(app.map{app.iteration},app.d{app.iteration},app.UIAxes);% title('Fitness'); caxis(d.fitnessRange);
        end

        % Button pushed function: GeneticPrototypingButton
        function GeneticPrototypingButtonPushed(app, event)
            app.classification{app.iteration} = extractClasses(app.map{app.iteration}.genes,'kmedoids',floor(app.noprototypesSlider.Value));
            % Show classes in similarity space
            viewClasses(app.map{app.iteration}.genes,app.classification{app.iteration},app.UIAxes2);
            % Show prototypes
            % Row and col placement
            numRows = ceil( size(app.classification{app.iteration}.protoX,1).^(2/3) );
            for i=1:size(app.classification{app.iteration}.protoX,1)
                placement(i,:) = [mod(i-1,numRows) floor((i-1)/numRows)]*app.d{app.iteration}.spacer;
            end
            app.UIAxes5.cla;
            showExample(app.UIAxes5,app.d{app.iteration},app.classification{app.iteration}.protoX,placement);
            axis(app.UIAxes5, 'equal');
            cla(app.UIAxes5_2);
            app.AddSelectionButton.Enable = true;
        end

        % Value changed function: ArchiveDropDown
        function ArchiveDropDownValueChanged(app, event)
            value = app.ArchiveDropDown.Value;
            setQD(app,value);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %% Workarounds for UIAxes not having callbacks..
            pos = event.Source.CurrentPoint;
            
            %  Click on map and show solution
            if strcmp(app.TabGroup.SelectedTab.Title,'Illumination') && length(app.map) <= app.iteration && ~isempty(app.map{app.iteration})
                figPos = app.UIAxes.InnerPosition;
                if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                    if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                        % Get position of mouse click relative to inner position (axis, not figure!)
                        relPos = app.UIAxes.CurrentPoint(1,1:2);
                        relPos = [(relPos(1)-app.UIAxes.XLim(1))/range(app.UIAxes.XLim),...
                            (relPos(2)-app.UIAxes.YLim(1))/range(app.UIAxes.YLim)];
                        if strcmp(app.ArchiveDropDown.Value,'voronoi')
                            features = app.map{app.iteration}.features(:,app.d{app.iteration}.selectedFeatures);
                            distances = pdist2(relPos,features);
                            [~,closestID] = min(distances);
                            closest = app.map{app.iteration}.genes(closestID,:);
                        elseif strcmp(app.ArchiveDropDown.Value,'grid')
                            features = app.map{app.iteration}.features(:,:,app.d{app.iteration}.selectedFeatures);
                            features = reshape(features,size(features,1)*size(features,2),[]);
                            distances = pdist2(relPos,features);
                            [~,closestID] = nanmin(distances);
                            [r,c] = size(app.map{app.iteration}.fitness);
                            [closestIDI,closestIDJ] = ind2sub([r c], closestID);
                            closest = squeeze(app.map{app.iteration}.genes(closestIDI,closestIDJ,:))';
                        end
                        app.UIAxes3_2.cla;
                        showExample(app.UIAxes3_2,app.d{app.iteration},closest);
                        hold(app.UIAxes,'on');
                        if strcmp(app.ArchiveDropDown.Value,'voronoi')
                            if numel(app.UIAxes.Children) > 4
                                delete(app.UIAxes.Children(1:2));
                            end
                            selPos = features(closestID,:);
                        elseif strcmp(app.ArchiveDropDown.Value,'grid')
                            if numel(app.UIAxes.Children) > 2
                                delete(app.UIAxes.Children(1:2));
                            end
                            selPos = [closestIDI,closestIDJ,];
                        end
                        scatter(app.UIAxes,selPos(1),selPos(2),100,[1 1 1],'filled');
                        scatter(app.UIAxes,selPos(1),selPos(2),32,[0 0 0],'filled');
                        axis(app.UIAxes3_2,'equal');
                        axis(app.UIAxes3_2,[-1 1 -1 1]);
                    end
                end
            end
            
            %  Click on prototypes and select
            if strcmp(app.TabGroup.SelectedTab.Title,'Analysis/Selection') && strcmp(app.AddSelectionButton.Enable,'on')% && length(app.classification) <= app.iteration && ~isempty(app.classification{app.iteration})
                figPos = app.UIAxes5.InnerPosition;
                if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                    if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                        relPos = app.UIAxes5.CurrentPoint(1,1:2);
                        polyAxes = app.UIAxes5.Children;
                        polys = []; numVerts = 0;
                        for i=1:length(polyAxes)
                            polys = [polys;polyAxes(i).Shape.Vertices];
                            numVerts(i+1) = numVerts(i) + size(polyAxes(i).Shape.Vertices,1);
                        end
                        numVerts(1) = [];
                        distances = pdist2(relPos,polys);
                        [~,closest] = min(distances);
                        closestPrototypes = closest<=numVerts; closestPrototypes = fliplr(closestPrototypes);
                        closestPrototypes = find(closestPrototypes);
                        if length(app.selectedPrototypes) < app.iteration
                            app.selectedPrototypes(app.iteration) = {closestPrototypes(end)};
                        else
                            app.selectedPrototypes{app.iteration}(end+1) = closestPrototypes(end);
                        end
                        % Row and col placement
                        for i=1:size(app.classification{app.iteration}.protoX(app.selectedPrototypes{app.iteration},:),1)
                            placement(i,:) = [(i-1)*app.d{app.iteration}.spacer 0];
                        end
                        cla(app.UIAxes5_2);
                        showExample(app.UIAxes5_2,app.d{app.iteration},app.classification{app.iteration}.protoX(app.selectedPrototypes{app.iteration},:),placement);
                        axis(app.UIAxes5_2,'equal');
                    end
                end
            end
            
            %  Click on prototypes and deselect
            if strcmp(app.TabGroup.SelectedTab.Title,'Analysis/Selection') && strcmp(app.AddSelectionButton.Enable,'on') && ~isempty(app.selectedPrototypes) && length(app.selectedPrototypes) == app.iteration~isempty(app.selectedPrototypes{app.iteration})
                figPos = app.UIAxes5_2.InnerPosition;
                if pos(1) > figPos(1) && pos(1) < (figPos(1) + figPos(3))
                    if pos(2) > figPos(2) && pos(2) < (figPos(2) + figPos(4))
                        if ~isempty(app.selectedPrototypes{app.iteration})
                            relPos = app.UIAxes5_2.CurrentPoint(1,1:2);
                            polyAxes = app.UIAxes5_2.Children;
                            polys = []; numVerts = 0;
                            for i=1:length(polyAxes)
                                polys = [polys;polyAxes(i).Shape.Vertices];
                                numVerts(i+1) = numVerts(i) + size(polyAxes(i).Shape.Vertices,1);
                            end
                            numVerts(1) = [];
                            distances = pdist2(relPos,polys);
                            [~,closest] = min(distances);
                            closestPrototypes = closest<=numVerts; closestPrototypes = fliplr(closestPrototypes);
                            closestPrototypes = find(closestPrototypes);
                            app.selectedPrototypes{app.iteration}(closestPrototypes(end)) = [];
                            % Row and col placement
                            cla(app.UIAxes5_2);
                            if ~isempty(app.selectedPrototypes{app.iteration})
                                for i=1:size(app.classification{app.iteration}.protoX(app.selectedPrototypes{app.iteration},:),1)
                                    placement(i,:) = [(i-1)*app.d{app.iteration}.spacer 0];
                                end
                                cla(app.UIAxes5_2);
                                showExample(app.UIAxes5_2,app.d{app.iteration},app.classification{app.iteration}.protoX(app.selectedPrototypes{app.iteration},:),placement);
                                axis(app.UIAxes5_2,'equal');
                            end
                        end
                    end
                end
            end
        end

        % Button pushed function: AddSelectionButton
        function AddSelectionButtonPushed(app, event)
            % Create UDHM based on selected prototypes
            if length(app.selectedPrototypes) >= app.iteration && ~isempty(app.selectedPrototypes{app.iteration})
                app.constraints{app.iteration} = setConstraints(app.classification{app.iteration},app.selectedPrototypes{app.iteration},[],'class');
                app.iteration = app.iteration + 1;
                app.IterationListBox.Items{end+1} = int2str(app.iteration);
                app.IterationListBox.Value = int2str(app.iteration);
                app.IterationListBox_2.Items{end+1} = int2str(app.iteration);
                app.IterationListBox_2.Value = int2str(app.iteration);
                app.consoleTextArea.Value = 'Selection model trained.';
                app.AddSelectionButton.Enable = false;
                
                % Initialize new domain and algorithm configuration for
                % this iteration
                app.d{app.iteration} = app.d{app.iteration-1};
                app.p{app.iteration} = app.p{app.iteration-1};
                % Show selection history
                app.showHistory(app.UIAxes6);
                app.showHistory(app.UIAxes6_2);
                %cla(app.UIAxes5);
                cla(app.UIAxes5_2);
                
            else
                app.consoleTextArea.Value = 'No prototypes selected!';
            end
            
        end

        % Value changed function: IterationListBox
        function IterationListBoxValueChanged(app, event)
            value = app.IterationListBox.Value;
            app.iteration = str2num(value);
            app.IterationListBox_2.Value = app.IterationListBox.Value;
            app.UIAxes.cla;
            viewMap(app.map{app.iteration},app.d{app.iteration},app.UIAxes);caxis(app.UIAxes,[0 1]);
            app.ExplicitButtonPushed([]);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.constraints{app.iteration} = [];
            app.AddSelectionButton.Enable = true;
            if app.iteration > 1
                app.iteration = app.iteration - 1 ;
                app.IterationListBox.Items(end) = [];
                app.IterationListBox.Value = int2str(app.iteration);
                app.IterationListBox_2.Items(end) = [];
                app.IterationListBox_2.Value = int2str(app.iteration);
            else
                app.consoleTextArea.Value = '';
            end
        end

        % Value changed function: IterationListBox_2
        function IterationListBox_2ValueChanged(app, event)
            value = app.IterationListBox_2.Value;
            app.iteration = value;
            app.IterationListBox.Value = app.IterationListBox_2.Value;
        end

        % Value changed function: UserSelectionPressureSlider
        function UserSelectionPressureSliderValueChanged(app, event)
            value = app.UserSelectionPressureSlider.Value;
            app.d{app.iteration}.penaltyWeight = value;
        end

        % Value changed function: DOFDropDown
        function DOFDropDownValueChanged(app, event)
            app.setDomain(app.domainDropDown.Value,str2num(app.DOFDropDown.Value));
        end

        % Button pushed function: ExplicitButton
        function ExplicitButtonPushed(app, event)
            cla(app.UIAxes7);
            placement = [];
            if strcmp(app.ArchiveDropDown.Value,'voronoi')
                genes = app.map{app.iteration}.genes;
                spacer = 15*app.d{app.iteration}.spacer;
                placement = spacer.* ([0 1] + [1 -1].*app.map{app.iteration}.features(:,app.d{app.iteration}.selectedFeatures));
            elseif strcmp(app.ArchiveDropDown.Value,'grid')
                spacer = app.d{app.iteration}.spacer;
                for i=1:length(app.map{app.iteration}.edges{1})-1
                    for j=1:length(app.map{app.iteration}.edges{2})-1
                        placement((j-1)*(length(app.map{app.iteration}.edges{2})-1)+i,:) = [i*spacer (length(app.map{app.iteration}.edges{2})-1-j)*spacer];
                    end
                end
                genes = reshape(app.map{app.iteration}.genes,size(app.map{app.iteration}.genes,1)*size(app.map{app.iteration}.genes,2),[]);
                
            end
            showExample(app.UIAxes7,app.d{app.iteration},genes,placement);
            
            axis(app.UIAxes7,spacer*[app.UIAxes.XLim app.UIAxes.YLim]);
            axis(app.UIAxes7,'equal');
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %save('savedStateGui.mat','app.p','app.d','app.map','app.classification','app.selectedPrototypes','app.constraints','app.iteration','app.fitfun');
            p = app.p;
            d = app.d;
            map = app.map;
            classification = app.classification;
            selectedPrototypes = app.selectedPrototypes;
            constraints = app.constraints;
            iteration = app.iteration;
            fitfun = app.fitfun;
            save('savedStateGui.mat', 'p', 'd', 'map', 'classification', 'selectedPrototypes', 'constraints', 'iteration', 'fitfun');
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            load('savedStateGui.mat');
            app.p = p;
            app.d = d;
            app.map = map;
            app.classification = classification;
            app.selectedPrototypes = selectedPrototypes;
            app.constraints = constraints;
            app.iteration = iteration;
            app.fitfun = fitfun;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Enable = 'off';
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Enable = 'off';
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Text = 'Save';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Enable = 'off';
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DomainTab = uitab(app.TabGroup);
            app.DomainTab.Title = 'Domain';
            app.UIAxes3 = uiaxes(app.DomainTab);
            title(app.UIAxes3, 'Example Solution')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.DomainConfigurationPanel = uipanel(app.DomainTab);
            app.DomainConfigurationPanel.Title = 'Domain Configuration';
            app.domainDropDownLabel = uilabel(app.DomainConfigurationPanel);
            app.domainDropDownLabel.Text = 'domain';
            app.domainDropDown = uidropdown(app.DomainConfigurationPanel);
            app.domainDropDown.Items = {'dropwave', 'shubert4', 'npoly', 'npoly_ffd'};
            app.domainDropDown.ValueChangedFcn = createCallbackFcn(app, @domainDropDownValueChanged, true);
            app.domainDropDown.Value = 'npoly_ffd';
            app.loadeddomainLabel = uilabel(app.DomainConfigurationPanel);
            app.loadeddomainLabel.Text = 'loaded domain: ';
            app.DOFDropDownLabel = uilabel(app.DomainConfigurationPanel);
            app.DOFDropDownLabel.Text = 'DOF';
            app.DOFDropDown = uidropdown(app.DomainConfigurationPanel);
            app.DOFDropDown.Items = {'8', '12', '16', '20', '24', '28', '32', '36', '40', '44', '48', '52', '56', '60'};
            app.DOFDropDown.ValueChangedFcn = createCallbackFcn(app, @DOFDropDownValueChanged, true);
            app.DOFDropDown.Value = '16';
            app.DescriptionTextAreaLabel = uilabel(app.DomainTab);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.DomainTab);
            app.DescriptionTextArea.Editable = 'off';
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.ModelConfigurationPanel = uipanel(app.TrainingTab);
            app.ModelConfigurationPanel.Title = 'Model Configuration';
            app.IlluminationTab = uitab(app.TabGroup);
            app.IlluminationTab.Title = 'Illumination';
            app.QDConfigurationPanel = uipanel(app.IlluminationTab);
            app.QDConfigurationPanel.Title = 'QD Configuration';
            app.ArchiveDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.ArchiveDropDownLabel.Text = 'Archive';
            app.ArchiveDropDown = uidropdown(app.QDConfigurationPanel);
            app.ArchiveDropDown.Items = {'grid', 'voronoi', ''};
            app.ArchiveDropDown.ValueChangedFcn = createCallbackFcn(app, @ArchiveDropDownValueChanged, true);
            app.ArchiveDropDown.Value = 'grid';
            app.nogenerationsEditFieldLabel = uilabel(app.QDConfigurationPanel);
            app.nogenerationsEditFieldLabel.Text = 'no. generations';
            app.nogenerationsEditField = uieditfield(app.QDConfigurationPanel, 'numeric');
            app.nogenerationsEditField.Limits = [1 100000];
            app.nogenerationsEditField.RoundFractionalValues = 'on';
            app.nogenerationsEditField.ValueChangedFcn = createCallbackFcn(app, @nogenerationsEditFieldValueChanged, true);
            app.nogenerationsEditField.Value = 100;
            app.featurexaxisDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.featurexaxisDropDownLabel.Text = 'feature x axis';
            app.featurexaxisDropDown = uidropdown(app.QDConfigurationPanel);
            app.featurexaxisDropDown.Items = {'default'};
            app.featurexaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featurexaxisDropDownValueChanged, true);
            app.featurexaxisDropDown.Value = 'default';
            app.featureyaxisDropDownLabel = uilabel(app.QDConfigurationPanel);
            app.featureyaxisDropDownLabel.Text = 'feature y axis';
            app.featureyaxisDropDown = uidropdown(app.QDConfigurationPanel);
            app.featureyaxisDropDown.Items = {'default'};
            app.featureyaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @featureyaxisDropDownValueChanged, true);
            app.featureyaxisDropDown.Value = 'default';
            app.UserSelectionPressureSliderLabel = uilabel(app.QDConfigurationPanel);
            app.UserSelectionPressureSliderLabel.Text = {'User'; ' Selection'; ' Pressure'};
            app.UserSelectionPressureSlider = uislider(app.QDConfigurationPanel);
            app.UserSelectionPressureSlider.Limits = [0 10];
            app.UserSelectionPressureSlider.MajorTicks = [0 5 10];
            app.UserSelectionPressureSlider.MajorTickLabels = {'0', '5', '10'};
            app.UserSelectionPressureSlider.ValueChangedFcn = createCallbackFcn(app, @UserSelectionPressureSliderValueChanged, true);
            app.UserSelectionPressureSlider.Value = 2;
            app.QDcommandsPanel = uipanel(app.IlluminationTab);
            app.QDcommandsPanel.Title = 'QD commands';
            app.InitRestartButton = uibutton(app.QDcommandsPanel, 'push');
            app.InitRestartButton.ButtonPushedFcn = createCallbackFcn(app, @InitRestartButtonPushed, true);
            app.InitRestartButton.Text = 'Init/Restart';
            app.RedrawButton = uibutton(app.QDcommandsPanel, 'push');
            app.RedrawButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawButtonPushed, true);
            app.RedrawButton.Text = 'Redraw';
            app.ExplicitButton = uibutton(app.QDcommandsPanel, 'push');
            app.ExplicitButton.ButtonPushedFcn = createCallbackFcn(app, @ExplicitButtonPushed, true);
            app.ExplicitButton.Text = 'Explicit';
            app.UIAxes = uiaxes(app.IlluminationTab);
            title(app.UIAxes, 'Phenotypic Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes3_2 = uiaxes(app.IlluminationTab);
            title(app.UIAxes3_2, 'Viewed Solution')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.IterationListBoxLabel = uilabel(app.IlluminationTab);
            app.IterationListBoxLabel.Text = 'Iteration';
            app.IterationListBox = uilistbox(app.IlluminationTab);
            app.IterationListBox.Items = {'1'};
            app.IterationListBox.ValueChangedFcn = createCallbackFcn(app, @IterationListBoxValueChanged, true);
            app.IterationListBox.Value = '1';
            app.UIAxes6 = uiaxes(app.IlluminationTab);
            title(app.UIAxes6, 'Selection History')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.UIAxes7 = uiaxes(app.IlluminationTab);
            title(app.UIAxes7, 'Explicit Phenotypic Map')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.UIAxes7_2 = uiaxes(app.IlluminationTab);
            title(app.UIAxes7_2, 'Previous Explicit Phenotypic Map')
            xlabel(app.UIAxes7_2, 'X')
            ylabel(app.UIAxes7_2, 'Y')
            app.UIAxes8 = uiaxes(app.IlluminationTab);
            title(app.UIAxes8, 'View from Similarity Space')
            xlabel(app.UIAxes8, '')
            ylabel(app.UIAxes8, '')
            app.UIAxes8.XTick = [];
            app.UIAxes8.YTick = [];
            app.AnalysisSelectionTab = uitab(app.TabGroup);
            app.AnalysisSelectionTab.Title = 'Analysis/Selection';
            app.AnalysisPanel = uipanel(app.AnalysisSelectionTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.GeneticPrototypingButton = uibutton(app.AnalysisPanel, 'push');
            app.GeneticPrototypingButton.ButtonPushedFcn = createCallbackFcn(app, @GeneticPrototypingButtonPushed, true);
            app.GeneticPrototypingButton.Text = 'Genetic Prototyping';
            app.noprototypesSliderLabel = uilabel(app.AnalysisPanel);
            app.noprototypesSliderLabel.Text = 'no. prototypes';
            app.noprototypesSlider = uislider(app.AnalysisPanel);
            app.noprototypesSlider.Limits = [1 14];
            app.noprototypesSlider.MajorTicks = [1 7 14];
            app.noprototypesSlider.MinorTicks = [2 3 4 5 6 7 8 9 11 12 13 14 15 16 17 18 19];
            app.noprototypesSlider.Value = 7;
            app.UIAxes2 = uiaxes(app.AnalysisSelectionTab);
            title(app.UIAxes2, 'Genotypic Space')
            xlabel(app.UIAxes2, '1')
            ylabel(app.UIAxes2, '2')
            app.UIAxes2.XTick = [0 0.5 1];
            app.UIAxes2.YTick = [0 0.5 1];
            app.UserSelectionPanel = uipanel(app.AnalysisSelectionTab);
            app.UserSelectionPanel.Title = 'User Selection';
            app.AddSelectionButton = uibutton(app.UserSelectionPanel, 'push');
            app.AddSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSelectionButtonPushed, true);
            app.AddSelectionButton.Text = 'Add Selection';
            app.RemoveButton = uibutton(app.UserSelectionPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.consoleTextAreaLabel = uilabel(app.AnalysisSelectionTab);
            app.consoleTextAreaLabel.Enable = 'off';
            app.consoleTextAreaLabel.Text = 'console';
            app.consoleTextArea = uitextarea(app.AnalysisSelectionTab);
            app.consoleTextArea.Editable = 'off';
            app.consoleTextArea.Enable = 'off';
            app.IterationListBox_2Label = uilabel(app.AnalysisSelectionTab);
            app.IterationListBox_2Label.Text = 'Iteration';
            app.IterationListBox_2 = uilistbox(app.AnalysisSelectionTab);
            app.IterationListBox_2.Items = {'1'};
            app.IterationListBox_2.ValueChangedFcn = createCallbackFcn(app, @IterationListBox_2ValueChanged, true);
            app.IterationListBox_2.Value = '1';
            app.UIAxes5 = uiaxes(app.AnalysisSelectionTab);
            title(app.UIAxes5, 'Prototypes')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes5.XLim = [0 2.5];
            app.UIAxes5_2 = uiaxes(app.AnalysisSelectionTab);
            title(app.UIAxes5_2, 'Selection')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            app.UIAxes5_2.XLim = [-1 20];
            app.UIAxes5_2.YLim = [-2 2];
            app.UIAxes6_2 = uiaxes(app.AnalysisSelectionTab);
            title(app.UIAxes6_2, 'Selection History')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demoGUItabs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end